classdef TextAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        TextAnalysisTab           matlab.ui.container.Tab
        HTML                      matlab.ui.control.HTML
        UITable                   matlab.ui.control.Table
        NoMostRepCharactersLabel  matlab.ui.control.Label
        MRCInput                  matlab.ui.control.NumericEditField
        HTML2                     matlab.ui.control.HTML
        StatusLamp                matlab.ui.control.Lamp
        StatusLampLabel           matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        sampleInput               matlab.ui.control.EditField
        AddtxtfileLabel           matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        varianceLabel             matlab.ui.control.Label
        KurtosisLabel             matlab.ui.control.Label
        SkewnessLabel             matlab.ui.control.Label
        MeanLabel_6               matlab.ui.control.Label
        kurtosisDisplay           matlab.ui.control.NumericEditField
        skewnessDisplay           matlab.ui.control.NumericEditField
        varianceDisplay           matlab.ui.control.NumericEditField
        meanDisplay               matlab.ui.control.NumericEditField
        RunButton                 matlab.ui.control.Button
        PMFGraphTab               matlab.ui.container.Tab
        PMFGraph                  matlab.ui.control.UIAxes
        CDFGraphTab               matlab.ui.container.Tab
        CDFGraph                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        referenceDecimalArray = 0:61; % decimal representation of chars.
        referenceCharactersArray = ['0','1','2','3','4','5','6','7','8','9','a','A','b','B','c','C','d','D','e','E','f','F','g','G','h','H','i','I','j','J','k','K','l','L','m','M','n','N','o','O','p','P','q','Q','r','R','s','S','t','T','u','U','v','V','w','W','x','X','y','Y','z','Z'];
        filename;       % path to the text file.
        N;              % Number of most reapeated Characters entered by the user.
        sample;         % filtered sample text.
        PMF;            % PMF array.
        CDF;            % CDF array.
        SZ;             % sample size.
        MRCs;           % Most Reapeated Character.
        NRMRCs;         % Number of Repeatition of Most Reapeated Character.
        Mean;           % Mean of the sample.
        Variance;       % Variance of the sample.
        Sigma;          % Standard deviation of the sample.
        Skew;           % Skewness of the Sample
        Kurt;           % Kurtosis of the sample.
    end
    
    methods (Access = private) 
        function setInitialSatate(app)
            app.sample = zeros;
            app.filename = '';
            app.N = 0;
            app.PMF = zeros;
            app.CDF = zeros;
            app.SZ = 0;
            app.MRCs = 0;
            app.NRMRCs = 0;
            app.Mean = 0;
            app.Variance = 0;
            app.Sigma = 0;
            app.Skew = 0;
            app.Kurt=0;
            app.StatusLamp.Color = [0.8 0.8 0.8];
            app.HTML.HTMLSource="<p></p>";
            app.HTML2.HTMLSource="<p></p>";
            app.meanDisplay.Value = 0;
            app.meanDisplay.FontColor = "white";
            app.varianceDisplay.Value = 0;
            app.varianceDisplay.FontColor = "white";
            app.skewnessDisplay.Value = 0;
            app.skewnessDisplay.FontColor = "white";
            app.kurtosisDisplay.Value = 0;
            app.kurtosisDisplay.FontColor = "white";
            app.UITable.Visible=0;
            cla(app.PMFGraph);
            cla(app.CDFGraph);
        end


        function selectSample(app)
            [file,path] = uigetfile('*.txt');
            app.filename = string(path)+string(file);
        end

    %{
        %---------------------> DOCUMENTATION <------------------------%
        selectSample() is a function that select the sample text file with
        .txt type and returns a a path to file refrenced to pc parent loc-
        ation. and assign this path to app.fileName.
    %}

        function filterSample(app,file)

            %check if file exist.
            if exist(file,"file")
                text = fopen(file);

                % store the text in the txt file in a string array.
                str = fscanf(text,'%s');

                %filter the string array to get only alphapets and numbers.
                s=str((double(str) > 64 & double(str)<91 )|(double(str) > 96 & ...
                    double(str)<123 )|(double(str) > 47 & double(str)<58 ));

                %check if filtered array is empty.
                if isempty(s)
                    app.StatusLamp.Color = "blue";
                    app.HTML2.HTMLSource="<html><head><style>p {color:blue;}</style></head><body><p>File contains no alphabet or numbers!</p></body></html>";
                    app.sampleInput.FontColor = "blue";
                else
                    
                    %once filtered array is not empty assign it to
                    %app.sample.
                    app.sample = s;
                end
            else
                
                % if file doesn't exist tell the user to enter valid file. 
                app.StatusLamp.Color = "red";
                app.HTML2.HTMLSource="<html><head><style>p {color:red;font-family:arial}</style></head><body><p>Enter a valid filename!</p></body></html>";
                app.sampleInput.FontColor = [1 0 0];
            end
        end


    %{
        %---------------------> DOCUMENTATION <------------------------%

        filterSample() is a function that takes the  random sample text
        and filters to extract the Alphapet and numbers from 0 to 9 and
        stores them in array.
    %}


        function getData(app,s)

            % initialize an copy the app. referenceCharactersArray. 
            X = app.referenceCharactersArray;

            %initialize a zeros array NREC to store the number of repeateition
            %for every character in the referenceCharactersArray.
            NREC = zeros([length(X)],1);
            
            %calculate number of repeatition in the sample text for every
            %character in the app.referenceCharactersArray.
            for I=1:length(X)
             for j=1:length(s)
                if X(I)==s(j)
                    NREC(I) = NREC(I)+1;
                end
             end
            end
            
            %calculate PMF for every character = number of repeatition/
            %total number of the characters in the app.sample.
            app.PMF = NREC/length(s);
            

            %initialize a zeros array to store the CDF for every character
            %in the referenceCharactersArray.
            b = zeros([length(X)],1);

            %calculate CDF = summation(PMF).
            sum =0;
            for I=1:length(app.PMF)
                sum = sum+app.PMF(I);
                b(I) = sum;
            end

            %assign b matrix generated to app.CDF.
            app.CDF = b;

            %calculate app.SZ = Number of elements in the app.sample.
            app.SZ=length(s);

            % Get MRCs and NRMRCs.
            [M,I] = maxk(NREC,int8(app.N));
            c="";
            d="";
            for i=1:length(I)
               c(i)=X(I(i));
               d(i)=M(i);
            end
            app.MRCs = transpose(c);
            app.NRMRCs = transpose(d);      
        end
        
        
        %{
            %---------------------> DOCUMENTATION <--------------------%

            getData() is a function that takes the  filterd array of the
            Alphapet and numbers from 0 to 9 as input and returns the
            app.PMF,app.CDF app.SZ,app.MRC,app.NRMRC.
        %}


        function Moments(app,pmf)
            
            X=app.referenceDecimalArray;
            % Calcultae Moments of the sample.
            E_X_1 = 0;
            E_X_2 = 0;
            E_X_3 = 0;
            E_X_4 = 0;
            for i=1:length(pmf)

                % First Moment = summation(i*PMF(i)).
                E_X_1 = E_X_1 + X(i)*pmf(i);

                % Second Moment = summation(i^2*PMF(i)).
                E_X_2 = E_X_2 + (X(i)^2)*pmf(i);

                % Thirdd Moment = summation(i^3*PMF(i)).
                E_X_3 = E_X_3 + (X(i)^3)*pmf(i);

                % Fourth Moment = summation(i^4*PMF(i)).
                E_X_4 = E_X_4 + (X(i)^4)*pmf(i);
            end

            % Calculate app.Mean.
            app.Mean = E_X_1;

            % Calculate app.Variance.
            app.Variance = ((E_X_2) - (app.Mean^2));

            % Calculate app.sigma.
            app.Sigma = sqrt(app.Variance);

            % Calculate app.skewness.
            app.Skew = ((E_X_3 - (3*app.Mean*app.Variance)-(app.Mean^3))/(app.Sigma^3));

            % Calculate app.kurtosis.
            app.Kurt = ((E_X_4 - (4*app.Mean*E_X_3) + (6*(app.Mean^2)*app.Variance) +(3*(app.Mean^4)))/(app.Sigma^4));
        end
        
        %{
            %---------------------> DOCUMENTATION <--------------------%

            Moments is a function that takes the PMF of a discrete random
            variable representing the letters and numbers sample and
            returns the mean,vaiance, sigma, skewness, and kurtosis of X.
        %}
        
   end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.setInitialSatate();
            app.N = app.MRCInput.Value;
            if ismatrix(app.sampleInput.Value)
                app.filterSample(app.sampleInput.Value);
                app.getData(app.sample);
                app.Moments(app.PMF)

    
                if (ismatrix(app.PMF)  && int8(sum(app.PMF))==1)
                    app.StatusLamp.Color = "green";
                        app.meanDisplay.Value = app.Mean;
                        app.meanDisplay.FontColor = [0 0 0];
                        app.varianceDisplay.Value = app.Variance;
                        app.varianceDisplay.FontColor = [0 0 0];

                    if (app.Sigma~=0)
                        app.skewnessDisplay.Value=app.Skew;
                        app.skewnessDisplay.FontColor = [0 0 0];

                        app.kurtosisDisplay.Value=app.Kurt;
                        app.kurtosisDisplay.FontColor = [0 0 0];
                    else
                        app.HTML.HTMLSource="<html><head><style>p {color:red;}</style></head><body><p>Skewness and kurtosis are not defined since variance = 0 </p></body></html>";
                    end
                    if (app.N>0)

                            tdata = table(app.MRCs,app.NRMRCs,'VariableNames',{'Character','Occurances'});
                            app.UITable.Data=tdata;
                            app.UITable.Visible=1;                       
                    else
                       app.UITable.Visible=0; 
                    end
                    
                    bar(app.PMFGraph,app.referenceDecimalArray,app.PMF);
                    plot(app.CDFGraph,app.referenceDecimalArray,app.CDF);
                end
            end

        end

        % Value changed function: varianceDisplay
        function varianceDisplayValueChanged(app, event)
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
          app.selectSample();
          app.sampleInput.Value = app.filename;
          app.sampleInput.FontColor = [0 0 0];
          app.sampleInput.FontSize = 18;
        end

        % Value changed function: meanDisplay
        function meanDisplayValueChanged(app, event)
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.StatusLamp.Color = [0.8 0.8 0.8];
            app.MRCInput.Value=0;
            app.sampleInput.Value = '';
            app.meanDisplay.Value = 0;
            app.meanDisplay.FontColor = "white";
            app.varianceDisplay.Value = 0;
            app.varianceDisplay.FontColor = "white";
            app.skewnessDisplay.Value = 0;
            app.skewnessDisplay.FontColor = "white";
            app.kurtosisDisplay.Value = 0;
            app.kurtosisDisplay.FontColor = "white";
            app.HTML.HTMLSource="<p></p>";
            app.HTML2.HTMLSource = "<p></p>";
            app.UITable.Visible=0;
            cla(app.PMFGraph);
            cla(app.CDFGraph);
        end

        % Value changing function: sampleInput
        function sampleInputValueChanging(app, event)
            changingValue = event.Value;
            if(changingValue)
            app.sampleInput.FontColor = [0 0 0];
            app.HTML.HTMLSource = "<p></p>";
            app.HTML2.HTMLSource = "<p></p>";
            end
        end

        % Value changed function: MRCInput
        function MRCInputValueChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TextAnalysisTab = uitab(app.TabGroup);
            app.TextAnalysisTab.Title = 'Text Analysis';
            app.RunButton = uibutton(app.TextAnalysisTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.meanDisplay = uieditfield(app.TextAnalysisTab, 'numeric');
            app.meanDisplay.ValueChangedFcn = createCallbackFcn(app, @meanDisplayValueChanged, true);
            app.varianceDisplay = uieditfield(app.TextAnalysisTab, 'numeric');
            app.varianceDisplay.ValueChangedFcn = createCallbackFcn(app, @varianceDisplayValueChanged, true);
            app.skewnessDisplay = uieditfield(app.TextAnalysisTab, 'numeric');
            app.kurtosisDisplay = uieditfield(app.TextAnalysisTab, 'numeric');
            app.MeanLabel_6 = uilabel(app.TextAnalysisTab);
            app.MeanLabel_6.Text = 'Mean';
            app.SkewnessLabel = uilabel(app.TextAnalysisTab);
            app.SkewnessLabel.Text = 'Skewness';
            app.KurtosisLabel = uilabel(app.TextAnalysisTab);
            app.KurtosisLabel.Text = 'Kurtosis';
            app.varianceLabel = uilabel(app.TextAnalysisTab);
            app.varianceLabel.Text = 'variance';
            app.Image = uiimage(app.TextAnalysisTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'add-document-33-807322.png');
            app.AddtxtfileLabel = uilabel(app.TextAnalysisTab);
            app.AddtxtfileLabel.Text = 'Add .txt file';
            app.sampleInput = uieditfield(app.TextAnalysisTab, 'text');
            app.sampleInput.ValueChangingFcn = createCallbackFcn(app, @sampleInputValueChanging, true);
            app.ClearButton = uibutton(app.TextAnalysisTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StatusLampLabel = uilabel(app.TextAnalysisTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.TextAnalysisTab);
            app.HTML2 = uihtml(app.TextAnalysisTab);
            app.HTML2.HTMLSource = '<p></p>';
            app.MRCInput = uieditfield(app.TextAnalysisTab, 'numeric');
            app.MRCInput.ValueChangedFcn = createCallbackFcn(app, @MRCInputValueChanged, true);
            app.NoMostRepCharactersLabel = uilabel(app.TextAnalysisTab);
            app.NoMostRepCharactersLabel.Text = 'No. Most Rep. Characters';
            app.UITable = uitable(app.TextAnalysisTab);
            app.UITable.ColumnName = {'Character'; 'Occurances'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.HTML = uihtml(app.TextAnalysisTab);
            app.HTML.HTMLSource = '<p><p>';
            app.PMFGraphTab = uitab(app.TabGroup);
            app.PMFGraphTab.Title = 'PMF Graph';
            app.PMFGraph = uiaxes(app.PMFGraphTab);
            title(app.PMFGraph, 'Probability Mass Function')
            xlabel(app.PMFGraph, 'chars')
            ylabel(app.PMFGraph, 'PMF')
            zlabel(app.PMFGraph, 'Z')
            app.PMFGraph.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CDFGraphTab = uitab(app.TabGroup);
            app.CDFGraphTab.Title = 'CDF Graph';
            app.CDFGraph = uiaxes(app.CDFGraphTab);
            title(app.CDFGraph, 'Cummulative Destribution Function')
            xlabel(app.CDFGraph, 'Chars')
            ylabel(app.CDFGraph, 'CDF')
            zlabel(app.CDFGraph, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end